classdef KuriosGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        ArchivoMenu             matlab.ui.container.Menu
        HerramientasMenu        matlab.ui.container.Menu
        AyudaMenu               matlab.ui.container.Menu
        TabGroup                matlab.ui.container.TabGroup
        DeviceInfoTab           matlab.ui.container.Tab
        Lamp                    matlab.ui.control.Lamp
        LampLabel               matlab.ui.control.Label
        ConexionSwitch          matlab.ui.control.Switch
        ConexionSwitchLabel     matlab.ui.control.Label
        AnalogoTab              matlab.ui.container.Tab
        BwModeDropDown          matlab.ui.control.DropDown
        BwModeDropDownLabel     matlab.ui.control.Label
        TriggerSwitch           matlab.ui.control.ToggleSwitch
        TriggerSwitchLabel      matlab.ui.control.Label
        SecuenciaTab            matlab.ui.container.Tab
        BwModeDropDown_3        matlab.ui.control.DropDown
        Spinner_2               matlab.ui.control.Spinner
        WavelengthSlider_2      matlab.ui.control.Slider
        SubmitButton            matlab.ui.control.Button
        posicionEditField       matlab.ui.control.EditField
        posicionEditFieldLabel  matlab.ui.control.Label
        TriggerSwitch_2         matlab.ui.control.ToggleSwitch
        TriggerSwitch_2Label    matlab.ui.control.Label
        UITable                 matlab.ui.control.Table
        ManualTab               matlab.ui.container.Tab
        Spinner                 matlab.ui.control.Spinner
        BwModeDropDown_2        matlab.ui.control.DropDown
        BwModeDropDown_2Label   matlab.ui.control.Label
        WavelengthSlider        matlab.ui.control.Slider
        WavelengthSliderLabel   matlab.ui.control.Label
        Label                   matlab.ui.control.Label
    end

    
    properties (Access = private)
        bwAvailable = ["BLACK","WIDE","MEDIUM","NARROW"]; % Description
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            filt = Kurios();
            app.BwModeDropDown.Items = app.bwAvailable;
            app.BwModeDropDown_2.Items = app.bwAvailable;            
            app.BwModeDropDown_3.Items = app.bwAvailable;
        end

        % Callback function: ConexionSwitch, ManualTab
        function ConexionSwitchValueChanged(app, event)
            value = app.ConexionSwitch.Value;
            if value=="Off"
                app.Lamp.Color = [1 0 0];
            else
                app.Lamp.Color = [0 1 0];
            end
        end

        % Drop down opening function: BwModeDropDown_3
        function BwModeDropDown_3Opening(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.ArchivoMenu = uimenu(app.UIFigure);
            app.ArchivoMenu.Text = 'Archivo';
            app.HerramientasMenu = uimenu(app.UIFigure);
            app.HerramientasMenu.Text = 'Herramientas';
            app.AyudaMenu = uimenu(app.UIFigure);
            app.AyudaMenu.Text = 'Ayuda';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DeviceInfoTab = uitab(app.TabGroup);
            app.DeviceInfoTab.Title = 'DeviceInfo';
            app.ConexionSwitchLabel = uilabel(app.DeviceInfoTab);
            app.ConexionSwitchLabel.Text = 'Conexion';
            app.ConexionSwitch = uiswitch(app.DeviceInfoTab, 'slider');
            app.ConexionSwitch.ValueChangedFcn = createCallbackFcn(app, @ConexionSwitchValueChanged, true);
            app.LampLabel = uilabel(app.DeviceInfoTab);
            app.LampLabel.Text = 'Lamp';
            app.Lamp = uilamp(app.DeviceInfoTab);
            app.AnalogoTab = uitab(app.TabGroup);
            app.AnalogoTab.Title = 'Analogo';
            app.TriggerSwitchLabel = uilabel(app.AnalogoTab);
            app.TriggerSwitchLabel.Text = 'Trigger';
            app.TriggerSwitch = uiswitch(app.AnalogoTab, 'toggle');
            app.TriggerSwitch.Items = {'Externo', 'Interno'};
            app.TriggerSwitch.Value = 'Externo';
            app.BwModeDropDownLabel = uilabel(app.AnalogoTab);
            app.BwModeDropDownLabel.Text = 'BwMode';
            app.BwModeDropDown = uidropdown(app.AnalogoTab);
            app.SecuenciaTab = uitab(app.TabGroup);
            app.SecuenciaTab.Title = 'Secuencia';
            app.UITable = uitable(app.SecuenciaTab);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            app.TriggerSwitch_2Label = uilabel(app.SecuenciaTab);
            app.TriggerSwitch_2Label.Text = 'Trigger';
            app.TriggerSwitch_2 = uiswitch(app.SecuenciaTab, 'toggle');
            app.TriggerSwitch_2.Items = {'Externo', 'Interno'};
            app.TriggerSwitch_2.Value = 'Externo';
            app.posicionEditFieldLabel = uilabel(app.SecuenciaTab);
            app.posicionEditFieldLabel.Text = 'posicion';
            app.posicionEditField = uieditfield(app.SecuenciaTab, 'text');
            app.SubmitButton = uibutton(app.SecuenciaTab, 'push');
            app.SubmitButton.Text = 'Submit';
            app.WavelengthSlider_2 = uislider(app.SecuenciaTab);
            app.WavelengthSlider_2.Limits = [420 730];
            app.WavelengthSlider_2.Value = 420;
            app.Spinner_2 = uispinner(app.SecuenciaTab);
            app.BwModeDropDown_3 = uidropdown(app.SecuenciaTab);
            app.BwModeDropDown_3.DropDownOpeningFcn = createCallbackFcn(app, @BwModeDropDown_3Opening, true);
            app.ManualTab = uitab(app.TabGroup);
            app.ManualTab.SizeChangedFcn = createCallbackFcn(app, @ConexionSwitchValueChanged, true);
            app.ManualTab.Title = 'Manual';
            app.Label = uilabel(app.ManualTab);
            app.WavelengthSliderLabel = uilabel(app.ManualTab);
            app.WavelengthSliderLabel.Text = 'Wavelength';
            app.WavelengthSlider = uislider(app.ManualTab);
            app.WavelengthSlider.Limits = [420 730];
            app.WavelengthSlider.Value = 420;
            app.BwModeDropDown_2Label = uilabel(app.ManualTab);
            app.BwModeDropDown_2Label.Text = 'BwMode';
            app.BwModeDropDown_2 = uidropdown(app.ManualTab);
            app.Spinner = uispinner(app.ManualTab);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = KuriosGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end